classdef FINTER < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FINTERLabel                     matlab.ui.control.Label
        IngresarDatosPanel              matlab.ui.container.Panel
        LotedeDatosYEditFieldLabel      matlab.ui.control.Label
        LotedeDatosYEditField           matlab.ui.control.EditField
        LotedeDatosXEditFieldLabel      matlab.ui.control.Label
        LotedeDatosXEditField           matlab.ui.control.EditField
        InterpolarmediantePolinomiodePanel  matlab.ui.container.Panel
        LagrangeCheckBox                matlab.ui.control.CheckBox
        NewtonGregoryProgresivoCheckBox  matlab.ui.control.CheckBox
        NewtonGregoryRegresivoCheckBox  matlab.ui.control.CheckBox
        InterpolarButton                matlab.ui.control.Button
        Resultados                      matlab.ui.control.EditField
        ResultadosLabel                 matlab.ui.control.Label
        EspecializarPolinomioenunValorPanel  matlab.ui.container.Panel
        IngresarValorKEditFieldLabel    matlab.ui.control.Label
        IngresarValorKEditField         matlab.ui.control.EditField
        CalcularButton                  matlab.ui.control.Button
        PolinomioEspecializadoenelvalorKEditFieldLabel  matlab.ui.control.Label
        PolinomioEspecializadoenelvalorKEditField  matlab.ui.control.NumericEditField
        FinalizarButton                 matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InterpolarButton
        function InterpolarButtonPushed(app, event)
        %Lo primero que tendría que hacer es preguntar por el método seleccionado - Completar

        xi=str2num(get(app.LotedeDatosXEditField,'Value'));
        yi=str2num(get(app.LotedeDatosYEditField,'Value'));
         
        n=length(xi);

        x=sym('x'); %esta funcion nos permite dejar la variable 'x' como simbolica

for j=1:n
producto=1;
    for i=1:j-1
        producto=producto*(x-xi(i)); %calculo del producto 1 superior de L
    end

producto2=1;
    for i=j+1:n
        producto2=producto2*(x-xi(i)); %calculo del producto 2 superior de 
    end

producto3=1;
    for i=1:j-1
        producto3=producto3*(xi(j)-xi(i)); %calculo del producto 3 inferior de L
    end

producto4=1;

    for i=j+1:n
        producto4=producto4*(xi(j)-xi(i)); %calculo del producto 4 inferior de L
    end

L(j)=(producto*producto2)/(producto3*producto4); %calculos de las L

%set(app.ResultadosTextArea,'String',j-1);
str2num(set(app.LotedeDatosXEditField,'String',L(j)));

end

pn=0;

for j=1:n
    pn=pn+L(j)*yi(j); %calculo del polinomio interpolante
end

%simplico la expresion
pn = vpa(simplify(pn),10);
set(app.Resultados,'Value',pn);
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FINTERLabel = uilabel(app.UIFigure);
            app.FINTERLabel.Text = 'FINTER';
            app.IngresarDatosPanel = uipanel(app.UIFigure);
            app.IngresarDatosPanel.Title = 'Ingresar Datos';
            app.LotedeDatosYEditFieldLabel = uilabel(app.IngresarDatosPanel);
            app.LotedeDatosYEditFieldLabel.Text = 'Lote de Datos Y:';
            app.LotedeDatosYEditField = uieditfield(app.IngresarDatosPanel, 'text');
            app.LotedeDatosYEditField.Value = '[7,4,5,60]';
            app.LotedeDatosXEditFieldLabel = uilabel(app.IngresarDatosPanel);
            app.LotedeDatosXEditFieldLabel.Text = 'Lote de Datos X:';
            app.LotedeDatosXEditField = uieditfield(app.IngresarDatosPanel, 'text');
            app.LotedeDatosXEditField.Value = '[5,6,8,7]';
            app.InterpolarmediantePolinomiodePanel = uipanel(app.UIFigure);
            app.InterpolarmediantePolinomiodePanel.Title = 'Interpolar mediante Polinomio de:';
            app.LagrangeCheckBox = uicheckbox(app.InterpolarmediantePolinomiodePanel);
            app.LagrangeCheckBox.Text = 'Lagrange';
            app.NewtonGregoryProgresivoCheckBox = uicheckbox(app.InterpolarmediantePolinomiodePanel);
            app.NewtonGregoryProgresivoCheckBox.Text = 'Newton Gregory Progresivo';
            app.NewtonGregoryRegresivoCheckBox = uicheckbox(app.InterpolarmediantePolinomiodePanel);
            app.NewtonGregoryRegresivoCheckBox.Text = 'Newton Gregory Regresivo';
            app.InterpolarButton = uibutton(app.InterpolarmediantePolinomiodePanel, 'push');
            app.InterpolarButton.ButtonPushedFcn = createCallbackFcn(app, @InterpolarButtonPushed, true);
            app.InterpolarButton.Text = 'Interpolar';
            app.Resultados = uieditfield(app.InterpolarmediantePolinomiodePanel, 'text');
            app.ResultadosLabel = uilabel(app.InterpolarmediantePolinomiodePanel);
            app.ResultadosLabel.Text = 'Resultados:';
            app.EspecializarPolinomioenunValorPanel = uipanel(app.UIFigure);
            app.EspecializarPolinomioenunValorPanel.Title = 'Especializar Polinomio en un Valor';
            app.IngresarValorKEditFieldLabel = uilabel(app.EspecializarPolinomioenunValorPanel);
            app.IngresarValorKEditFieldLabel.Text = 'Ingresar Valor K:';
            app.IngresarValorKEditField = uieditfield(app.EspecializarPolinomioenunValorPanel, 'text');
            app.CalcularButton = uibutton(app.EspecializarPolinomioenunValorPanel, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.PolinomioEspecializadoenelvalorKEditFieldLabel = uilabel(app.EspecializarPolinomioenunValorPanel);
            app.PolinomioEspecializadoenelvalorKEditFieldLabel.Text = 'Polinomio Especializado en el valor K:';
            app.PolinomioEspecializadoenelvalorKEditField = uieditfield(app.EspecializarPolinomioenunValorPanel, 'numeric');
            app.FinalizarButton = uibutton(app.UIFigure, 'push');
            app.FinalizarButton.Text = 'Finalizar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FINTER

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end